classdef HellingsDownsPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PulsarCatalogDirEditField       matlab.ui.control.EditField
        PulsarCatalogDirEditFieldLabel  matlab.ui.control.Label
        simulateButton                  matlab.ui.control.Button
        numofpulsars                    matlab.ui.control.NumericEditField
        ofpulsarsEditFieldLabel         matlab.ui.control.Label
        OuputDir                        matlab.ui.control.EditField
        OuputDirEditFieldLabel          matlab.ui.control.Label
        numofrealizations               matlab.ui.control.NumericEditField
        ofrealizationsEditFieldLabel    matlab.ui.control.Label
        numofsources                    matlab.ui.control.NumericEditField
        ofsourcesEditFieldLabel         matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)

        function []=gensimdata(path_to_parameters,path_to_pulsar_catalog,path_to_output,frqRng)
            %Generate PTA data realizations with multiple SMBHB sources
            %GENSIMDATA(P,D,O,[LowFrqRng,UpFrqRng])
            %P is the path to a .mat file containing the parameters for generating the
            %data. See the help for PARAMETERS for details about the parameters that must be
            %specified. D is the path to the file containing information about the
            %pulsars in the PTA. See 'survey_ska.mat' for an example of this file. O is
            %the path to the folder where the data realizations will be stored. frqRng
            %is the frequency range of the sources, if it is empty, use the default
            %value, i.e. the whole frequency range.

            % Authors: Yi-Qian Qian, Yan Wang, Soumya Mohanty, Sep 16, 2018

            %Sep 2018
            %Original code by YW. Turned into a function and made compatible for multi-source by YQ.

            %Get constants
            dy2yr = genptaconsts('dy2yr');

            load(path_to_parameters);% load all the constants and parameters

            % master directory for simulated data
            simDataDir = path_to_output;

            rng('shuffle')  % initialize the random number generator using a different seed
            % rng(1) % for repeatable works.

            %% ==== Generate random GW sources ====
            for srlz = 1:Nsrlz
                [AmpOut,alphaOut,deltaOut,fgwOut,iotaOut,thetaNOut,phi0Out,r]=GenerateRandomGWSource(Ns);
                % put check for frequency above Nyquist freq
                cadence = 14; % biweekly;
                Nyquist_freq = 1/2 * 1/(cadence*24*3600); % Nyquist frequency (Hz)

                % setting up the freq filter
                if nargin == 3
                    disp("Searching whole band.");
                    if max(fgwOut) > Nyquist_freq
                        disp("Warning: Frequency above Nyquist frequency:" +Nyquist_freq)
                        disp("Will exclude all sources above Nyquist frequency")
                    end
                    Index = (fgwOut <= Nyquist_freq);
                    % disp(['Index is: ', num2str(Index)]);
                    if sum(Index) == 0
                        disp('There is no source');
                        continue
                    elseif sum(Index) > 0
                        Amp_tmp = AmpOut(Index);
                        alpha_tmp = alphaOut(Index);
                        delta_tmp = deltaOut(Index);
                        iota_tmp = iotaOut(Index);
                        thetaN_tmp = thetaNOut(Index);
                        phi0_tmp = phi0Out(Index);
                        omega_tmp = fgwOut(Index)*2*pi*24*365*3600; % convert sec^-1 (Hz) to rad yr^-1
                        NNs = sum(Index);
                        %     disp("The number of sources is:" + NNs)
                    end
                elseif nargin == 4
                    fgwl = frqRng(1);
                    fgwh = frqRng(2);
                    disp("The lower limit is:" + fgwl);
                    disp("The upper limit is:" + fgwh);
                    Index = (fgwOut >= fgwl & fgwOut <= fgwh);% select the sources within bands
                    Amp_tmp = AmpOut(Index);
                    omega_tmp = 2*pi * fgwOut(Index) * 3.156*10^7; % unit change to yr^-1
                    alpha_tmp = alphaOut(Index);
                    delta_tmp = deltaOut(Index);
                    iota_tmp = iotaOut(Index);
                    thetaN_tmp = thetaNOut(Index);
                    phi0_tmp = phi0Out(Index);
                    NNs = sum(Index);
                end

                disp("The number of sources is: " + NNs);
                disp("The size of Amp_tmp is: " + length(Amp_tmp));
                %disp("m is:" + m);
                %% ==== Constructing a pulsar timing array using Np pulsars ====
                % read in the pulsar catalogue simulated for SKA
                skamsp=load(path_to_pulsar_catalog);% load input data
                [~,I]=sort(skamsp.D);

                % sky location of the pulsars in the equatorial coordinate
                % we need to transfer from hr angle and degree to radian
                alphaP=zeros(Np,1);  % right ascension, in radian
                deltaP=zeros(Np,1);  % declination, in radian
                distP=zeros(Np,1);  % (parallax) distance from SSB to pulsars, from mas/pc to ly
                kp=zeros(Np,3);  % unit vector pointing from SSB to pulsars,
                sd=zeros(Np,1);  % standard deviation of noise for different pulsar
                dy=zeros(N,1);  % observation epoch, in day
                yr=zeros(N,1);  % observation epoch, in year


                %%
                InList=zeros(1,2);
                OutList=zeros(1,2);
                for i=1:1:Np
                    %     if OutList(i,1)>0
                    %         alphaP(i)=OutList(i,1);  % in rad
                    %     else
                    %         alphaP(i)=OutList(i,1)+2*pi;  % in rad
                    %     end

                    InList(1,1)=skamsp.l(I(i));
                    InList(1,2)=skamsp.b(I(i));

                    % transfer the galactic coord. to equatorial coord.
                    %[OutList,TotRot]=coco(InList,'g','j2000.0','d','r');
                    [OutList,~]=coco(InList,'g','j2000.0','d','r');

                    alphaP(i)=OutList(1,1);  % in rad
                    deltaP(i)=OutList(1,2);  % in rad
                    % convert kpc to ly
                    kilo=1000;
                    pc2ly=3.26;
                    distP(i)=skamsp.D(I(i))*kilo*pc2ly; % in ly
                    sd(i)=1.0*10^(-7);  % 100 ns

                end

                % % -------------------------------
                % % transfer (hr,min,sec) and (degree,min,sec) to radian; mas/kpc to ly
                % tmp1='J0030+0451';
                % alphaP(1)=(0*15+30*15/60)*pi/180;
                % deltaP(1)=(4+51/60)*pi/180;
                % distP(1)=0.28*kilo*pc2ly;  % in ly
                % sd(1)=79.2*10^(-8); %0.148;
                %
                % pname={tmp1 tmp2 tmp3 tmp4 tmp5 tmp6 tmp7 tmp8 tmp9};c
                tmp1='place_holder';
                pname={tmp1};

                % sky location of pulsars in Cartesian coordinate
                for i=1:1:Np
                    kp(i,1)=cos(deltaP(i))*cos(alphaP(i));
                    kp(i,2)=cos(deltaP(i))*sin(alphaP(i));
                    kp(i,3)=sin(deltaP(i));
                end

                for i=1:1:N
                    dy(i)=start+(i-1)*deltaT;  % dates conducting observations, in MJD
                    yr(i)=2004.5+(i-1)*deltaT*dy2yr;
                end

                simParams = struct('Np',Np,'N',N,'sd',sd,...
                    'alphaP',alphaP,'deltaP',deltaP,'distP',distP,'kp',kp);

                % -------------------------------
                % calculate timing residuals induced by GW for each pulsar
                %Amp=1.3*10^(-7);  % overall amplitude of timing residuals, the same for all pulsars, sec
                timingResiduals=zeros(Np,N);  % signal, i.e. GW induced timing residuals, Np pulsars, N observations
                timingResiduals_tmp=zeros(Np,N);   % signal without noise
                phiI=zeros(Np,1);  % arbitrary phase for each pulsar, relative distance

                noise=zeros(Np,N);  % noise
                %sd=0.1*10^(-7);  % standard deviation of the normal distribtion (sec)

                CA4filenames=cell(Nrlz+Nnis,1);  % cell array for file names of simulated data

                perfect_fitness=zeros(NNs,1);  % fitness value for the true parameters

                %Standardized true parameter values
                %stdTrueCoord = zeros(1,12);  % 4+Np
                stdTrueCoord = zeros(NNs,7); % 7 parameters other than pulsar phases

                % calculate SNR
                snr_chr2_tmp=zeros(Np,NNs);  % squared characteristic snr for each pulsar and source
                %snr_chr=zeros(Np,1);  % characteristic srn = <\rho> = sqrt[(h,h)] srn of signal -- the strenghth of signal
                %snr_mf=0;  % snr of matched filter = \rho = x/\sigma, normalized matched filter

                genHypothesis='H1 data';

                tmp=zeros(1,N); % noiseless timing residuals from a source
                for i=1:1:Np  % number of pulsar
                    for j=1:1:NNs  % number of GW source

                        % GW sky location in Cartesian coordinate
                        k=zeros(1,3);  % unit vector pointing from SSB to source
                        k(1)=cos(delta_tmp(j))*cos(alpha_tmp(j));
                        k(2)=cos(delta_tmp(j))*sin(alpha_tmp(j));
                        k(3)=sin(delta_tmp(j));
                        theta=acos(k*kp(i,:)');
                        %sprintf('%d pulsar theta=%g',i,theta)
                        %phiI(i)=mod(phi0-omega*distP(i)*(1-cos(theta)), 2*pi);  % modulus after division
                        %phiI(i)=mod(2*phi0-omega_tmp(l)*distP(i)*(1-cos(theta)), pi);  % modulus after division, YW 09/10/13
                        phiI(i)=mod(phi0_tmp(j)-0.5*omega_tmp(j)*distP(i)*(1-cos(theta)), pi);  % modulus after division, YW 04/30/14 check original def. of phiI

                        %disp(['pulsar = ', num2str(i), ' ', num2str(phiI(i))])

                        tmp = FullResiduals(alpha_tmp(j),delta_tmp(j),omega_tmp(j),phi0_tmp(j),phiI(i),alphaP(i),deltaP(i),...
                            Amp_tmp(j),iota_tmp(j),thetaN_tmp(j),theta,yr);

                        timingResiduals_tmp(i,:) = timingResiduals_tmp(i,:) + tmp';

                        %fftsignal(i,:)=fft(timingResiduals_tmp(i,:));

                        % calculate the perfect fitness value

                        snr_chr2_tmp(i,j) = dot(tmp,tmp)/sd(i)^2;

                        % standardization of the true coordinates
                        stdTrueCoord(j,1)=(alpha_tmp(j)-xmaxmin(1,2))/(xmaxmin(1,1)-xmaxmin(1,2));  % [0, 2*pi]
                        stdTrueCoord(j,2)=(delta_tmp(j)-xmaxmin(2,2))/(xmaxmin(2,1)-xmaxmin(2,2));  % [-pi/2, pi/2]
                        stdTrueCoord(j,3)=(omega_tmp(j)-xmaxmin(3,2))/(xmaxmin(3,1)-xmaxmin(3,2));  % [2, 20]
                        stdTrueCoord(j,4)= mod(phi0_tmp(j),pi)/pi;  % [0, pi]
                        stdTrueCoord(j,5)=(log10(Amp_tmp(j))-xmaxmin(5,2))/(xmaxmin(5,1)-xmaxmin(5,2));
                        stdTrueCoord(j,6)=(iota_tmp(j)-xmaxmin(6,2))/(xmaxmin(6,1)-xmaxmin(6,2));
                        stdTrueCoord(j,7)=(thetaN_tmp(j)-xmaxmin(7,2))/(xmaxmin(7,1)-xmaxmin(7,2));

                    end

                end

                snr_chr=sqrt(sum(snr_chr2_tmp,1));  % sum of elements in each column

                % signal + noise realizations
                nf=0;  % counter for number of files
                for jj=1:1:Nrlz

                    nf=nf+1;

                    rlz_id=jj; %num2str(jj);

                    % structure to store the id tag for each metadata file
                    snr_id=0;  % not used for GWB
                    loc_id=0;
                    omg_id=0;
                    id=struct('snr_id',snr_id,'loc_id',loc_id,'omg_id',omg_id,'rlz_id',rlz_id);

                    for i=1:1:Np

                        % generating a realization of noise
                        noise(i,:)=sd(i)*randn(1,N);  % Gaussian noise
                        % calculate the actual snr
                        %fftnoise(i,:)=fft(noise(i,:));

                        %snr_tmp=snr_tmp+ fftsignal(i,:)*fftsignal(i,:)/fftnoise(i,:);
                        timingResiduals(i,:)=timingResiduals_tmp(i,:)+noise(i,:);  % add noise on signal

                    end

                    inParams = struct('Np',Np,'N',N,'s',timingResiduals,'sd',sd,...
                        'alphaP',alphaP,'deltaP',deltaP,'kp',kp,'yr',yr,...
                        'xmaxmin',xmaxmin);

                    for j=1:1:NNs
                        perfect_fitness(j) = LLR_PSOmpp(stdTrueCoord(j,:),inParams);  % - LogLikelihoodRatio, minimization
                        %true_fitness = fitnessTrue_ie(alpha_tmp(j),delta_tmp(j),omega_tmp(l),Amp,iota,thetaN,phi0,phiI,inParams);
                    end

                    %disp(['In simulator2: perfect_fitness: ', num2str(perfect_fitness)]);

                    % save metadata into a file for each realization (file name rule)
                    %filename=strcat('snr',num2str(ii),'loc',num2str(j),'omg',num2str(l),'rlz',num2str(jj),'.mat');
                    filename=strcat('GWBsimDataSKA','Srlz',num2str(srlz),'Nrlz',num2str(jj),'.mat'); % change Srlz when changing rng seed.
                    snr=0;  % this variable is not useful here
                    alpha=alpha_tmp;
                    delta=delta_tmp;
                    omega=omega_tmp;
                    Amp = Amp_tmp;
                    iota = iota_tmp;
                    thetaN = thetaN_tmp;
                    phi0 = phi0_tmp;
                    CA4filenames{nf}=filename;

                    save([simDataDir,filesep,filename],'genHypothesis','snr','alpha','delta','omega','iota','thetaN','phi0',...
                        'timingResiduals','noise','yr', 'pname','id','simParams','perfect_fitness','snr_chr','timingResiduals_tmp','Amp');

                end

                % save the GW source parameters into a file, have duplication with data files
                filename=strcat('GWB_','Srlz',num2str(srlz),'.mat');
                save([simDataDir,filesep,filename],'Amp','alpha','delta',...
                    'omega','iota','thetaN','phi0','r','stdTrueCoord');

                % simulating noise without signal
                %Nnis=10; %100;  % number of realization of noise
                perfect_fitness = 0;
                snr_chr = 0;
                timingResiduals_tmp=0;
                for ii=1:1:Nnis

                    genHypothesis='H0 data';
                    snr_id=0; %'0';
                    loc_id=0; %'0';
                    omg_id=0; %'0';
                    rlz_id=ii; %num2str(ii);

                    id=struct('snr_id',snr_id,'loc_id',loc_id,'omg_id',omg_id,'rlz_id',rlz_id);

                    for i=1:1:Np

                        % generating a realization of noise
                        noise(i,:)=sd(i)*randn(1,N);  % Gaussian noise
                        timingResiduals(i,:)=noise(i,:);
                        %timingResiduals(i,:)=timingResiduals_tmp(i,:)+noise(i,:);  % add noise on signal

                    end

                    % save metadata into a file for each realization (file name rule)
                    filename=strcat('srlz',num2str(srlz),'noise',num2str(ii),'.mat');
                    snr=0.0;  %snr_tmp(ii);
                    alpha=0.0;  %alpha_tmp(j);
                    delta=0.0;  %delta_tmp(j);
                    omega=0.0;  %omega_tmp(l);
                    iota=0.0;
                    thetaN=0.0;
                    phi0=0.0;
                    save([simDataDir,filesep,filename],'genHypothesis','snr','alpha','delta','omega','iota','thetaN','phi0',...
                        'timingResiduals','noise','yr','pname','id','simParams','perfect_fitness','snr_chr','timingResiduals_tmp','Amp');
                    CA4filenames{nf+ii}=filename;

                end
            end
        end

        function []=parameters(Ns,Np,Nrlz,Nsrlz,Nnis,outDir)
            % given the numer of random GW sources [Ns], the number of pulsars
            % [Np], the number of noise realizations in H1 data [Nrlz]
            % and the number of realization of noise in H0 [Nnis],outputs a
            % .mat file stores these variables.
            %% A script to generate the all the parameters and save it to .mat
            % for the function FSGWB

            % Yi-Qian, Sep 16, 2018
            %% parameters
            % ==== Generate random GW sources ====
            %Ns = Ns;  % number of GW sources
            %[Amp,alpha_tmp,delta_tmp,fgw,iota,thetaN,phi0,r]=GenerateRandomGWSource(Ns);
            %omega_tmp = 2*pi* fgw * 3.156*10^7;  % convert sec^-1 (Hz) to yr^-1
            %Np=NP;  % number of pulsars in the timing array

            % starting epoch of the observations
            start=53187;  % Modified Julian Day, 'July 1, 2004'
            %finish=start+ceil(365.25*5);  % approximately, set 5 yrs
            deltaT=14;  % observation cadence, in days, set biweekly
            %N=389;  % 14.9 yr, 128;  % number of biweekly observations for all pulsars, fft prefer 2^n
            N=130;  % 5 yrs biweekly
            %N=260;  % 10 yrs biweekly
            %%dy=zeros(N,1);  % observation epoch, in day
            %%yr=zeros(N,1);  % observation epoch, in year

            % noise
            %Nrlz=NoiseReali_H1;  % number of noise realizations H1
            %%noise=zeros(Np,N);  % noise
            %sd=0.1*10^(-7);  % standard deviation of the normal distribtion (sec)

            %Nnis=NoiseReali_H0;  % number of realization of noise only cases H0
            %%
            % set the range of the parameters
            xmaxmin=zeros(7,2);  % x_max, x_min for each parameter x
            xmaxmin(1,1)=2*pi;  % alpha
            xmaxmin(1,2)=0.0;
            xmaxmin(2,1)=pi/2;  % delta
            xmaxmin(2,2)=-pi/2;
            xmaxmin(3,1)=81.8345;% Nyquist frequency %200.0;  % angular velocity -- omega for GW
            xmaxmin(3,2)=1.0;
            xmaxmin(4,1)=pi;  % initial phase
            xmaxmin(4,2)=0;
            xmaxmin(5,1)=-6.0;  %-5.0;  %10^(-6);  % amplitude, in sec
            xmaxmin(5,2)=-15.0;  % -10.0;  %10^(-8);
            xmaxmin(6,1)=pi;  % inclination
            xmaxmin(6,2)=0;
            xmaxmin(7,1)=pi;  % polarization
            xmaxmin(7,2)=0;
            %%
            searchParamsFile = [outDir,filesep,'searchParams_GWBsimDataSKA'];
            simParamsFile = [outDir,filesep,'GWBsimDataSKAParams'];
            save(searchParamsFile,'xmaxmin'); % save the searching range of parameters
            save(simParamsFile,'xmaxmin','start','deltaT','N','Ns','Np',...
                'Nrlz','Nsrlz','Nnis');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            simDataDir = app.OuputDir.Value;
            files = dir([simDataDir,filesep,'GWBsimDataSKASrlz*.mat']);
            fileNames = sort_nat({files.name});
            Np = app.numofpulsars.Value; % number of pulsars
            Nrlz = app.numofrealizations.Value;

            a=zeros(1,Np*(Np-1)); % there are 17 pulsars in use so there are 16*17/2 coefficients
            b=zeros(1,Np*(Np-1));
            binSize = 1; % bin size
            bins = 0:binSize:180;
            bin_avg_CE = zeros(1,3); % averaged correlation coefficients over each bin

            parfor i = 1:1:Nrlz % calculate 100 times
                [CE,thetaC,ct]=CorrelationCoefficient(simDataDir, fileNames{i});
                a(i,:) = CE;
                b(i,:)=thetaC;
            end

            AC=mean(a); % averaged cross-correlation coefficients over realizations
            AT=mean(b);

            % average over bins
            parfor bin = 0:binSize:180
                index = find(AT>bin & AT<bin+binSize);
                bin_avg_CE(bin+1) = mean(AC(index));
            end

            % average for each realization
            bin_avg_CE_prlz = zeros(1,3);
            for n = 1:Nrlz
                for bin = 0:binSize:180
                    idx = find(b(n,:) > bin & b(n,:) < bin+binSize );
                    bin_avg_CE_prlz(n,bin+1) = mean(a(n,idx));
                end
            end

            % calculate the variance
            for bin = 0:binSize:180
                var(bin+1) = sum((bin_avg_CE_prlz(:,bin+1)-bin_avg_CE(bin+1)).^2)/(length(bin_avg_CE)-1);
            end

            std = sqrt(var);

            % Analytical
            theta = linspace(0,180,1000);
            x = (1-cos(theta*pi/180))/2;
            y = 3/2 .* x .* log(x) - 1/4 .* x + 1/2;

            % Plot
            hold(app.UIAxes,'on')
            for n = 1:Nrlz
                %     fa_rlz = 0.5/bin_avg_CE_prlz(n,1);
                %     lines = plot(bins, fa_rlz*bin_avg_CE_prlz(n,:), '-', 'color',...
                %     '#C1BEBE '); % with calibration factor fa_rlz
                lines = plot(app.UIAxes, bins, bin_avg_CE_prlz(n,:), '-', 'color', '#C1BEBE');
            end
            % avg_fig = plot(bins,fa*bin_avg_CE,'r-');
            avg_fig = plot(app.UIAxes, bins,bin_avg_CE,'r-');
            theo_fig = plot(app.UIAxes, theta,y,'b--');
            boundedline(bins, bin_avg_CE, std,'r', app.UIAxes, 'alpha');
            hold(app.UIAxes,'off')
            legend([lines, avg_fig, theo_fig],{'Realizations','bin averaged','theoretical'})

        end

        % Button pushed function: simulateButton
        function simulateButtonPushed(app, event)
            pulsar_cata_dir = app.PulsarCatalogDirEditField.Value;
            Ns = app.numofsources.Value;
            Np = app.numofpulsars.Value; % for the specific pulsar catalog
            Nrlz = 1; % number of H1 realization with different noise realizations
            Nsrlz = app.numofrealizations.Value; % number of H1 realization with different src realizations
            Nnis = 1; % number of H0 realization
            %frqRng = [8.26e-8,4.130e-7];% Angular velocity is converted to Hz by divided by 2*pi*365*24*3600
            outDir = app.OuputDir.Value;
            if isfolder(outDir) == 0
                mkdir(outDir);
            end

            parameters(Ns,Np,Nrlz,Nsrlz,Nnis,outDir);
            % when run 'gensimdata' multiple times need to change 'Srlzs' in it, if rng
            % seed is not fixed. When rng seed is fixed, it always gives the same Srlz.
            gensimdata([outDir,filesep,'GWBsimDataSKAParams.mat'],...
                pulsar_cata_dir,...
                outDir); %, [0,4e-7]); % frequency range
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {598, 598};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ofsourcesEditFieldLabel = uilabel(app.LeftPanel);
            app.ofsourcesEditFieldLabel.Text = '# of sources';
            app.numofsources = uieditfield(app.LeftPanel, 'numeric');
            app.ofrealizationsEditFieldLabel = uilabel(app.LeftPanel);
            app.ofrealizationsEditFieldLabel.Text = '# of realizations';
            app.numofrealizations = uieditfield(app.LeftPanel, 'numeric');
            app.OuputDirEditFieldLabel = uilabel(app.LeftPanel);
            app.OuputDirEditFieldLabel.Text = 'Ouput Dir';
            app.OuputDir = uieditfield(app.LeftPanel, 'text');
            app.ofpulsarsEditFieldLabel = uilabel(app.LeftPanel);
            app.ofpulsarsEditFieldLabel.Text = '# of pulsars';
            app.numofpulsars = uieditfield(app.LeftPanel, 'numeric');
            app.simulateButton = uibutton(app.LeftPanel, 'push');
            app.simulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulateButton.Text = 'simulate';
            app.PulsarCatalogDirEditFieldLabel = uilabel(app.LeftPanel);
            app.PulsarCatalogDirEditFieldLabel.Text = 'Pulsar Catalog Dir';
            app.PulsarCatalogDirEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Hellings-Downs Curve')
            xlabel(app.UIAxes, 'Angle')
            ylabel(app.UIAxes, 'Correlation Coefficients')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HellingsDownsPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end